classdef gliomaApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ToggleTumorMaskSwitch       matlab.ui.control.Switch
        ToggleTumorMaskSwitchLabel  matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        ImageAcquisitionProtocolButtonGroup  matlab.ui.container.ButtonGroup
        T2Button                    matlab.ui.control.ToggleButton
        T2FLAIRButton               matlab.ui.control.ToggleButton
        T1GdButton                  matlab.ui.control.ToggleButton
        T1Button                    matlab.ui.control.ToggleButton
        Slider                      matlab.ui.control.Slider
        BrowseButton                matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
    end

    
    properties (Access = private)
        directory;     % Directory path containing .h5 files
        filedata;      % List of filedata
        slice_images;  % Slice image data from .h5 files - 155x4x240x240
        slice_masks;   % Slice mask data from .h5 files - 155x3x240x240
        mri_image;     % Image displaying the MRI
    end
    
    methods (Access = private)
        
        % Read dataset from the directory path
        function readDataset(app)
            % Grab file list by creating a mask of dir entries that are files
            % and applying that mask to the list of the names of the files
            % Then it transposes the array to be a column (using ')
            app.filedata = dir(app.directory);
            filelist = {app.filedata(~[app.filedata.isdir]).name}';

            % Remove all files that are not .h5 files
            % https://stackoverflow.com/a/15604522
            % Throw an error if 154 .h5 files are not found
            filelist = filelist(~cellfun('isempty', regexpi(filelist, '\.h5$')));
            if length(filelist) ~= 155
                errordlg("Error: Must have 155 .h5 files in directory");
            end

            % natsort function credit - Stephen Cobeldick
            % https://au.mathworks.com/matlabcentral/fileexchange/34464-customizable-natural-order-sort
            filelist = natsort(filelist);
            
            % Formatting for the table to display the files
            app.UITable.Data = filelist;
            app.UITable.ColumnEditable = false;

            % Read data from all files
            for i = 1:155
                filepath = sprintf('%s\\volume_1_slice_%d.h5', app.directory, i-1);
                app.slice_images{i} = h5read(filepath, '/image');
                app.slice_masks{i} = h5read(filepath, '/mask');
            end
            displaySlice(app, 75);
        end

        function displaySlice(app, slice)
            data = app.slice_images{slice};
            % Transpose data so in form 240x240x1
            data = permute(data, [2 3 1]);
            % Normalize the image channel to 0-1, then convert to uint8
            image = data(:,:,2);
            image = (image-min(image(:))) ./ (max(image(:)));
            image = im2uint8(image);
            imshow(image, "Parent", app.mri_image);
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set initial value of output image
            app.mri_image = uiaxes(app.UIFigure);
            app.mri_image.Position = [240 80 240 240];
            app.mri_image.XTick = [];
            app.mri_image.YTick = [];
            app.mri_image.Box = 'on';

            % Initialize slices data objects
            app.slice_images = cell(155, 1);
            app.slice_masks = cell(155, 1);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            selected_dir = uigetdir('', 'Select a Directory');
            % Only call displayFiles if selected_dir points to a new directory
            if selected_dir ~= 0 & ~strcmp(selected_dir, app.directory)
                app.directory = selected_dir;
                readDataset(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Tooltip = {'Load an Image from File'};
            app.BrowseButton.Text = 'Browse...';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 154];
            app.Slider.MajorTicks = [0 154];
            app.Slider.MajorTickLabels = {'0', '154'};
            app.ImageAcquisitionProtocolButtonGroup = uibuttongroup(app.UIFigure);
            app.ImageAcquisitionProtocolButtonGroup.Title = 'Image Acquisition Protocol';
            app.T1Button = uitogglebutton(app.ImageAcquisitionProtocolButtonGroup);
            app.T1Button.Text = 'T1';
            app.T1Button.Value = true;
            app.T1GdButton = uitogglebutton(app.ImageAcquisitionProtocolButtonGroup);
            app.T1GdButton.Text = 'T1Gd';
            app.T2FLAIRButton = uitogglebutton(app.ImageAcquisitionProtocolButtonGroup);
            app.T2FLAIRButton.Text = 'T2-FLAIR';
            app.T2Button = uitogglebutton(app.ImageAcquisitionProtocolButtonGroup);
            app.T2Button.Text = 'T2';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ToggleTumorMaskSwitchLabel = uilabel(app.UIFigure);
            app.ToggleTumorMaskSwitchLabel.Text = 'Toggle Tumor Mask';
            app.ToggleTumorMaskSwitch = uiswitch(app.UIFigure, 'slider');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gliomaApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end